classdef ExcelGrapherApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExcelGrapherAppPanel         matlab.ui.container.Panel
        SaveGraphButton              matlab.ui.control.Button
        AddYLabelField               matlab.ui.control.EditField
        AddYLabelEditFieldLabel      matlab.ui.control.Label
        AddXLabelField               matlab.ui.control.EditField
        AddXLabelEditFieldLabel      matlab.ui.control.Label
        ShowYGridCheckBox            matlab.ui.control.CheckBox
        ShowXGirdCheckBox            matlab.ui.control.CheckBox
        GraphLineWidthSlider         matlab.ui.control.Slider
        GraphLineWidthSliderLabel    matlab.ui.control.Label
        AxesLineWidthSlider          matlab.ui.control.Slider
        AxesLineWidthSliderLabel     matlab.ui.control.Label
        GraphTitleField              matlab.ui.control.EditField
        AddGraphTitleEditFieldLabel  matlab.ui.control.Label
        GraphTypeDropdown            matlab.ui.control.DropDown
        GraphTypeDropDownLabel       matlab.ui.control.Label
        GraphColorButton             matlab.ui.control.Button
        FigureColorButton            matlab.ui.control.Button
        GenerateGraphButton          matlab.ui.control.Button
        LoadDataButton               matlab.ui.control.Button
        DataTable                    matlab.ui.control.Table
        ExcelGrapherAppLabel         matlab.ui.control.Label
        GraphVisualizationAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Open file dialog to select an Excel file
            [file, path] = uigetfile('*.xlsx', 'Select an Excel file');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                % If a file is selected, read the data from the file
                fullPath = fullfile(path, file);
                data = readtable(fullPath);
        
                % Set the data to the DataTable
                app.DataTable.Data = data;
            end
        end

        % Button pushed function: GenerateGraphButton
        function GenerateGraphButtonPushed(app, event)
            graphType = app.GraphTypeDropdown.Value; % Get the selected graph type
            data = app.DataTable.Data; % Assuming you have data loaded in DataTable
            graphTitle = app.GraphTitleField.Value; % Get the title from the text field

            % Check if data is not empty
            if isempty(data)
                uialert(app.UIFigure, 'Load data first.', 'Data Required');
                return;
            end
        
            % Clear previous graph and reset properties
            cla(app.GraphVisualizationAxes);
            reset(app.GraphVisualizationAxes); % Resets properties like axis limits and labels
        
            % Generate the selected type of graph
            switch graphType
                case 'Line'
                    plot(app.GraphVisualizationAxes, data.X, data.Y);
                case 'Bar'
                    bar(app.GraphVisualizationAxes, data.X, data.Y);
                case 'Scatter'
                    scatter(app.GraphVisualizationAxes, data.X, data.Y);
                case 'Pie'
                    pie(app.GraphVisualizationAxes, data.X);
                    % Note: Pie charts do not typically use axis titles
            end

            % Set the graph title
            title(app.GraphVisualizationAxes, graphTitle);
        end

        % Button pushed function: FigureColorButton
        function FigureColorButtonPushed(app, event)
            newColor = uisetcolor([1 1 1], 'Select Axes Background Color'); % Default white
            if length(newColor) == 3  % Check if the user didn't cancel the color dialog
                app.GraphVisualizationAxes.Color = newColor; % Set the new color to the axes background
            end
        end

        % Button pushed function: GraphColorButton
        function GraphColorButtonPushed(app, event)
            newColor = uisetcolor([1 1 1], 'Select Graph Color'); % Default white
            if length(newColor) == 3  % User did not cancel the operation
                % Obtain the list of all children in the axes
                allChildren = app.GraphVisualizationAxes.Children;
                
                % Determine the type of graph by inspecting the type of the children
                for child = allChildren'
                    switch class(child)
                        case 'matlab.graphics.chart.primitive.Bar'
                            set(child, 'FaceColor', newColor); % Change color of bar graph
                        case 'matlab.graphics.chart.primitive.Line'
                            set(child, 'Color', newColor); % Change color of line graph
                        case 'matlab.graphics.chart.primitive.Scatter'
                            set(child, 'CData', newColor); % Change color of scatter plot
                        case 'matlab.graphics.primitive.Patch'
                            if strcmp(child.Tag, 'pie') % Ensure it's a pie chart
                                set(child, 'FaceColor', newColor); % Change color of pie chart
                            end
                    end
                end
            end
        end

        % Value changed function: AxesLineWidthSlider
        function AxesLineWidthSliderValueChanged(app, event)
            newValue = app.AxesLineWidthSlider.Value;  % Get the new line width value from the slider
    
            % Validate the newValue to ensure it's a finite, positive number
            if isfinite(newValue) && newValue > 0
                app.GraphVisualizationAxes.LineWidth = newValue;  % Set the axes line width
            else
                disp('Invalid line width value');  % Optionally, notify about the invalid value
                app.AxesLineWidthSlider.Value = app.GraphVisualizationAxes.LineWidth;  % Reset to the current valid value
            end
        end

        % Value changed function: GraphLineWidthSlider
        function GraphLineWidthSliderValueChanged(app, event)
            newValue = app.GraphLineWidthSlider.Value;  % Get the new line width value from the slider
    
            % Check if the newValue is positive
            if newValue <= 0
                return;  % Optionally, show a warning or reset the slider
            end
        
            % Check if there's a graph plotted
            if ~isempty(app.GraphVisualizationAxes.Children)
                for child = app.GraphVisualizationAxes.Children'
                    if isa(child, 'matlab.graphics.chart.primitive.Line')
                        child.LineWidth = newValue;  % Update line width for line graphs
                    elseif isa(child, 'matlab.graphics.chart.primitive.Bar')
                        % Note: Bar objects might not support direct 'LineWidth' change for the bars themselves
                        % child.BarWidth = newValue; Uncomment or modify if adjusting bar properties
                    elseif isa(child, 'matlab.graphics.chart.primitive.Scatter')
                        % Ensure the newValue is appropriate
                        scaledSize = max(10, newValue * 10);  % Prevent non-positive sizes
                        child.SizeData = scaledSize;  % Update marker size for scatter plots
                    end
                end
            end
        end

        % Value changed function: ShowXGirdCheckBox
        function ShowXGirdCheckBoxValueChanged(app, event)
            value = app.ShowXGirdCheckBox.Value;
            if value
                app.GraphVisualizationAxes.XGrid = 'on';  % Turn on X grid
            else
                app.GraphVisualizationAxes.XGrid = 'off'; % Turn off X grid
            end
        end

        % Value changed function: ShowYGridCheckBox
        function ShowYGridCheckBoxValueChanged(app, event)
            value = app.ShowYGridCheckBox.Value; 
            if value
                app.GraphVisualizationAxes.YGrid = 'on';  % Turn on Y grid
            else
                app.GraphVisualizationAxes.YGrid = 'off'; % Turn off Y grid
            end
        end

        % Value changed function: AddXLabelField
        function AddXLabelFieldValueChanged(app, event)
            xLabel = app.AddXLabelField.Value;  % Get the label from the text field
            app.GraphVisualizationAxes.XLabel.String = xLabel;  % Set the X-axis label
        end

        % Value changed function: AddYLabelField
        function AddYLabelFieldValueChanged(app, event)
            yLabel = app.AddYLabelField.Value;  % Get the label from the text field
            app.GraphVisualizationAxes.YLabel.String = yLabel;  % Set the Y-axis label
        end

        % Button pushed function: SaveGraphButton
        function SaveGraphButtonPushed(app, event)
            % Define the file types you want to support for saving
            filter = {'*.png'; '*.jpg'; '*.tif'; '*.pdf'; '*.eps'};
            [file, path, indx] = uiputfile(filter, 'Save Graph As');
        
            % Check if the user canceled the file saving dialog
            if isequal(file, 0) || isequal(path, 0)
                disp('User canceled the save operation.');
            else
                % Full file path
                filename = fullfile(path, file);
                
                % Save the figure based on selected file type
                switch indx
                    case 1
                        exportgraphics(app.GraphVisualizationAxes, filename, 'Resolution', 300);
                    case 2
                        exportgraphics(app.GraphVisualizationAxes, filename, 'Resolution', 300);
                    case 3
                        exportgraphics(app.GraphVisualizationAxes, filename, 'Resolution', 300);
                    case 4
                        exportgraphics(app.GraphVisualizationAxes, filename);
                    case 5
                        exportgraphics(app.GraphVisualizationAxes, filename);
                end
                disp(['Graph saved to ', filename]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExcelGrapherAppPanel = uipanel(app.UIFigure);
            app.ExcelGrapherAppPanel.Title = 'Excel Grapher App';
            app.GraphVisualizationAxes = uiaxes(app.ExcelGrapherAppPanel);
            title(app.GraphVisualizationAxes, 'Graph Visualization')
            xlabel(app.GraphVisualizationAxes, 'X')
            ylabel(app.GraphVisualizationAxes, 'Y')
            zlabel(app.GraphVisualizationAxes, 'Z')
            app.ExcelGrapherAppLabel = uilabel(app.ExcelGrapherAppPanel);
            app.ExcelGrapherAppLabel.Text = 'Excel Grapher App';
            app.DataTable = uitable(app.ExcelGrapherAppPanel);
            app.DataTable.ColumnName = {'X'; 'Y'};
            app.DataTable.RowName = {};
            app.LoadDataButton = uibutton(app.ExcelGrapherAppPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.GenerateGraphButton = uibutton(app.ExcelGrapherAppPanel, 'push');
            app.GenerateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphButtonPushed, true);
            app.GenerateGraphButton.Text = 'Generate Graph';
            app.FigureColorButton = uibutton(app.ExcelGrapherAppPanel, 'push');
            app.FigureColorButton.ButtonPushedFcn = createCallbackFcn(app, @FigureColorButtonPushed, true);
            app.FigureColorButton.Text = 'Figure Color';
            app.GraphColorButton = uibutton(app.ExcelGrapherAppPanel, 'push');
            app.GraphColorButton.ButtonPushedFcn = createCallbackFcn(app, @GraphColorButtonPushed, true);
            app.GraphColorButton.Text = 'Graph Color';
            app.GraphTypeDropDownLabel = uilabel(app.ExcelGrapherAppPanel);
            app.GraphTypeDropDownLabel.Text = 'Graph Type ';
            app.GraphTypeDropdown = uidropdown(app.ExcelGrapherAppPanel);
            app.GraphTypeDropdown.Items = {'Line', 'Bar', 'Scatter', 'Pie'};
            app.GraphTypeDropdown.Value = 'Line';
            app.AddGraphTitleEditFieldLabel = uilabel(app.ExcelGrapherAppPanel);
            app.AddGraphTitleEditFieldLabel.Text = 'Add Graph Title';
            app.GraphTitleField = uieditfield(app.ExcelGrapherAppPanel, 'text');
            app.AxesLineWidthSliderLabel = uilabel(app.ExcelGrapherAppPanel);
            app.AxesLineWidthSliderLabel.Text = 'Axes Line Width';
            app.AxesLineWidthSlider = uislider(app.ExcelGrapherAppPanel);
            app.AxesLineWidthSlider.Limits = [0 5];
            app.AxesLineWidthSlider.ValueChangedFcn = createCallbackFcn(app, @AxesLineWidthSliderValueChanged, true);
            app.GraphLineWidthSliderLabel = uilabel(app.ExcelGrapherAppPanel);
            app.GraphLineWidthSliderLabel.Text = 'Graph Line Width';
            app.GraphLineWidthSlider = uislider(app.ExcelGrapherAppPanel);
            app.GraphLineWidthSlider.Limits = [0 5];
            app.GraphLineWidthSlider.ValueChangedFcn = createCallbackFcn(app, @GraphLineWidthSliderValueChanged, true);
            app.ShowXGirdCheckBox = uicheckbox(app.ExcelGrapherAppPanel);
            app.ShowXGirdCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowXGirdCheckBoxValueChanged, true);
            app.ShowXGirdCheckBox.Text = 'Show X Gird ';
            app.ShowYGridCheckBox = uicheckbox(app.ExcelGrapherAppPanel);
            app.ShowYGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowYGridCheckBoxValueChanged, true);
            app.ShowYGridCheckBox.Text = 'Show Y Grid ';
            app.AddXLabelEditFieldLabel = uilabel(app.ExcelGrapherAppPanel);
            app.AddXLabelEditFieldLabel.Text = 'Add X Label';
            app.AddXLabelField = uieditfield(app.ExcelGrapherAppPanel, 'text');
            app.AddXLabelField.ValueChangedFcn = createCallbackFcn(app, @AddXLabelFieldValueChanged, true);
            app.AddYLabelEditFieldLabel = uilabel(app.ExcelGrapherAppPanel);
            app.AddYLabelEditFieldLabel.Text = 'Add Y Label';
            app.AddYLabelField = uieditfield(app.ExcelGrapherAppPanel, 'text');
            app.AddYLabelField.ValueChangedFcn = createCallbackFcn(app, @AddYLabelFieldValueChanged, true);
            app.SaveGraphButton = uibutton(app.ExcelGrapherAppPanel, 'push');
            app.SaveGraphButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButtonPushed, true);
            app.SaveGraphButton.Text = 'Save Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExcelGrapherApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end