classdef Excel_Grapher_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        DataTablePanel               matlab.ui.container.Panel
        DataTableLabel               matlab.ui.control.Label
        LoadDataButton               matlab.ui.control.Button
        DataTable                    matlab.ui.control.Table
        CenterPanel                  matlab.ui.container.Panel
        ExcelGrapherAppPanel         matlab.ui.container.Panel
        ExcelGrapherAppLabel         matlab.ui.control.Label
        SaveGraphButton              matlab.ui.control.Button
        GraphTitleField              matlab.ui.control.EditField
        AddGraphTitleEditFieldLabel  matlab.ui.control.Label
        GraphTypeDropdown            matlab.ui.control.DropDown
        GraphTypeDropDownLabel       matlab.ui.control.Label
        GenerateGraphButton          matlab.ui.control.Button
        GraphVisualizationAxes       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        ControllersPanel             matlab.ui.container.Panel
        GraphColorButton             matlab.ui.control.Button
        FigureColorButton            matlab.ui.control.Button
        GraphLineWidthSlider         matlab.ui.control.Slider
        GraphLineWidthSliderLabel    matlab.ui.control.Label
        AxesLineWidthSlider          matlab.ui.control.Slider
        AxesLineWidthSliderLabel     matlab.ui.control.Label
        ControllersLabel             matlab.ui.control.Label
        ShowYGridCheckBox            matlab.ui.control.CheckBox
        ShowXGirdCheckBox            matlab.ui.control.CheckBox
        AddXLabelField               matlab.ui.control.EditField
        AddXLabelEditFieldLabel      matlab.ui.control.Label
        AddYLabelField               matlab.ui.control.EditField
        AddYLabelEditFieldLabel      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Open file dialog to select an Excel file
            [file, path] = uigetfile('*.xlsx', 'Select an Excel file');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                % If a file is selected, read the data from the file
                fullPath = fullfile(path, file);
                data = readtable(fullPath);
        
                % Set the data to the DataTable
                app.DataTable.Data = data;
            end
        end

        % Button pushed function: GenerateGraphButton
        function GenerateGraphButtonPushed(app, event)
            graphType = app.GraphTypeDropdown.Value; % Get the selected graph type
            data = app.DataTable.Data; % Assuming you have data loaded in DataTable
            graphTitle = app.GraphTitleField.Value; % Get the title from the text field

            % Check if data is not empty
            if isempty(data)
                uialert(app.UIFigure, 'Load data first.', 'Data Required');
                return;
            end
        
            % Clear previous graph and reset properties
            cla(app.GraphVisualizationAxes);
            reset(app.GraphVisualizationAxes); % Resets properties like axis limits and labels
        
            % Generate the selected type of graph
            switch graphType
                case 'Line'
                    plot(app.GraphVisualizationAxes, data.X, data.Y);
                case 'Bar'
                    bar(app.GraphVisualizationAxes, data.X, data.Y);
                case 'Scatter'
                    scatter(app.GraphVisualizationAxes, data.X, data.Y);
                case 'Pie'
                    pie(app.GraphVisualizationAxes, data.X);
                    % Note: Pie charts do not typically use axis titles
            end

            % Set the graph title
            title(app.GraphVisualizationAxes, graphTitle);
        end

        % Button pushed function: FigureColorButton
        function FigureColorButtonPushed(app, event)
            newColor = uisetcolor([1 1 1], 'Select Axes Background Color'); % Default white
            if length(newColor) == 3  % Check if the user didn't cancel the color dialog
                app.GraphVisualizationAxes.Color = newColor; % Set the new color to the axes background
            end
        end

        % Button pushed function: GraphColorButton
        function GraphColorButtonPushed(app, event)
            newColor = uisetcolor([1 1 1], 'Select Graph Color'); % Default white
            if length(newColor) == 3  % User did not cancel the operation
                % Obtain the list of all children in the axes
                allChildren = app.GraphVisualizationAxes.Children;
                
                % Determine the type of graph by inspecting the type of the children
                for child = allChildren'
                    switch class(child)
                        case 'matlab.graphics.chart.primitive.Bar'
                            set(child, 'FaceColor', newColor); % Change color of bar graph
                        case 'matlab.graphics.chart.primitive.Line'
                            set(child, 'Color', newColor); % Change color of line graph
                        case 'matlab.graphics.chart.primitive.Scatter'
                            set(child, 'CData', newColor); % Change color of scatter plot
                        case 'matlab.graphics.primitive.Patch'
                            if strcmp(child.Tag, 'pie') % Ensure it's a pie chart
                                set(child, 'FaceColor', newColor); % Change color of pie chart
                            end
                    end
                end
            end
        end

        % Value changed function: AxesLineWidthSlider
        function AxesLineWidthSliderValueChanged(app, event)
            newValue = app.AxesLineWidthSlider.Value;  % Get the new line width value from the slider
    
            % Validate the newValue to ensure it's a finite, positive number
            if isfinite(newValue) && newValue > 0
                app.GraphVisualizationAxes.LineWidth = newValue;  % Set the axes line width
            else
                disp('Invalid line width value');  % Optionally, notify about the invalid value
                app.AxesLineWidthSlider.Value = app.GraphVisualizationAxes.LineWidth;  % Reset to the current valid value
            end
        end

        % Value changed function: GraphLineWidthSlider
        function GraphLineWidthSliderValueChanged(app, event)
            newValue = app.GraphLineWidthSlider.Value;  % Get the new line width value from the slider

            % Ensure the newValue is positive
            if newValue <= 0
                return;  % Optionally, show a warning or reset the slider
            end
        
            % Check if there's a graph plotted
            if ~isempty(app.GraphVisualizationAxes.Children)
                for child = app.GraphVisualizationAxes.Children'
                    if isa(child, 'matlab.graphics.chart.primitive.Line')
                        child.LineWidth = newValue;  % Update line width for line graphs
                    elseif isa(child, 'matlab.graphics.chart.primitive.Bar')
                        % Update the line width of the bar edges
                        child.LineWidth = newValue;  % Assuming MATLAB version supports this
                    elseif isa(child, 'matlab.graphics.chart.primitive.Scatter')
                        % Update marker size for scatter plots
                        scaledSize = max(10, newValue * 10);  % Prevent non-positive sizes
                        child.SizeData = scaledSize;
                    end
                end
            end
        end

        % Value changed function: AddXLabelField
        function AddXLabelFieldValueChanged(app, event)
            xLabel = app.AddXLabelField.Value;  % Get the label from the text field
            app.GraphVisualizationAxes.XLabel.String = xLabel;  % Set the X-axis label
        end

        % Value changed function: AddYLabelField
        function AddYLabelFieldValueChanged(app, event)
            yLabel = app.AddYLabelField.Value;  % Get the label from the text field
            app.GraphVisualizationAxes.YLabel.String = yLabel;  % Set the Y-axis label
        end

        % Value changed function: ShowXGirdCheckBox
        function ShowXGirdCheckBoxValueChanged(app, event)
            value = app.ShowXGirdCheckBox.Value;
            if value
                app.GraphVisualizationAxes.XGrid = 'on';  % Turn on X grid
            else
                app.GraphVisualizationAxes.XGrid = 'off'; % Turn off X grid
            end
        end

        % Value changed function: ShowYGridCheckBox
        function ShowYGridCheckBoxValueChanged(app, event)
            value = app.ShowYGridCheckBox.Value; 
            if value
                app.GraphVisualizationAxes.YGrid = 'on';  % Turn on Y grid
            else
                app.GraphVisualizationAxes.YGrid = 'off'; % Turn off Y grid
            end
        end

        % Button pushed function: SaveGraphButton
        function SaveGraphButtonPushed(app, event)
            % Define the file types you want to support for saving
            filter = {'*.png'; '*.jpg'; '*.tif'; '*.pdf'; '*.eps'};
            [file, path, indx] = uiputfile(filter, 'Save Graph As');
        
            % Check if the user canceled the file saving dialog
            if isequal(file, 0) || isequal(path, 0)
                disp('User canceled the save operation.');
            else
                % Full file path
                filename = fullfile(path, file);
                
                % Save the figure based on selected file type
                switch indx
                    case 1
                        exportgraphics(app.GraphVisualizationAxes, filename, 'Resolution', 300);
                    case 2
                        exportgraphics(app.GraphVisualizationAxes, filename, 'Resolution', 300);
                    case 3
                        exportgraphics(app.GraphVisualizationAxes, filename, 'Resolution', 300);
                    case 4
                        exportgraphics(app.GraphVisualizationAxes, filename);
                    case 5
                        exportgraphics(app.GraphVisualizationAxes, filename);
                end
                disp(['Graph saved to ', filename]);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataTablePanel = uipanel(app.LeftPanel);
            app.DataTablePanel.Title = 'Data Table';
            app.DataTable = uitable(app.DataTablePanel);
            app.DataTable.ColumnName = {'X'; 'Y'};
            app.DataTable.RowName = {};
            app.LoadDataButton = uibutton(app.DataTablePanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DataTableLabel = uilabel(app.DataTablePanel);
            app.DataTableLabel.Text = 'Data Table';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ExcelGrapherAppPanel = uipanel(app.CenterPanel);
            app.ExcelGrapherAppPanel.Title = 'Excel Grapher App';
            app.GraphVisualizationAxes = uiaxes(app.ExcelGrapherAppPanel);
            title(app.GraphVisualizationAxes, 'Graph Visualization')
            xlabel(app.GraphVisualizationAxes, 'X')
            ylabel(app.GraphVisualizationAxes, 'Y')
            zlabel(app.GraphVisualizationAxes, 'Z')
            app.GenerateGraphButton = uibutton(app.ExcelGrapherAppPanel, 'push');
            app.GenerateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphButtonPushed, true);
            app.GenerateGraphButton.Text = 'Generate Graph';
            app.GraphTypeDropDownLabel = uilabel(app.ExcelGrapherAppPanel);
            app.GraphTypeDropDownLabel.Text = 'Graph Type ';
            app.GraphTypeDropdown = uidropdown(app.ExcelGrapherAppPanel);
            app.GraphTypeDropdown.Items = {'Line', 'Bar', 'Scatter', 'Pie'};
            app.GraphTypeDropdown.Value = 'Line';
            app.AddGraphTitleEditFieldLabel = uilabel(app.ExcelGrapherAppPanel);
            app.AddGraphTitleEditFieldLabel.Text = 'Add Graph Title';
            app.GraphTitleField = uieditfield(app.ExcelGrapherAppPanel, 'text');
            app.SaveGraphButton = uibutton(app.ExcelGrapherAppPanel, 'push');
            app.SaveGraphButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButtonPushed, true);
            app.SaveGraphButton.Text = 'Save Graph';
            app.ExcelGrapherAppLabel = uilabel(app.ExcelGrapherAppPanel);
            app.ExcelGrapherAppLabel.Text = 'Excel Grapher App';
            app.RightPanel = uipanel(app.GridLayout);
            app.ControllersPanel = uipanel(app.RightPanel);
            app.ControllersPanel.Title = 'Controller''s ';
            app.AddYLabelEditFieldLabel = uilabel(app.ControllersPanel);
            app.AddYLabelEditFieldLabel.Text = 'Add Y Label';
            app.AddYLabelField = uieditfield(app.ControllersPanel, 'text');
            app.AddYLabelField.ValueChangedFcn = createCallbackFcn(app, @AddYLabelFieldValueChanged, true);
            app.AddXLabelEditFieldLabel = uilabel(app.ControllersPanel);
            app.AddXLabelEditFieldLabel.Text = 'Add X Label';
            app.AddXLabelField = uieditfield(app.ControllersPanel, 'text');
            app.AddXLabelField.ValueChangedFcn = createCallbackFcn(app, @AddXLabelFieldValueChanged, true);
            app.ShowXGirdCheckBox = uicheckbox(app.ControllersPanel);
            app.ShowXGirdCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowXGirdCheckBoxValueChanged, true);
            app.ShowXGirdCheckBox.Text = 'Show X Gird ';
            app.ShowYGridCheckBox = uicheckbox(app.ControllersPanel);
            app.ShowYGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowYGridCheckBoxValueChanged, true);
            app.ShowYGridCheckBox.Text = 'Show Y Grid ';
            app.ControllersLabel = uilabel(app.ControllersPanel);
            app.ControllersLabel.Text = 'Controller''s ';
            app.AxesLineWidthSliderLabel = uilabel(app.ControllersPanel);
            app.AxesLineWidthSliderLabel.Text = 'Axes Line Width';
            app.AxesLineWidthSlider = uislider(app.ControllersPanel);
            app.AxesLineWidthSlider.Limits = [0 5];
            app.AxesLineWidthSlider.ValueChangedFcn = createCallbackFcn(app, @AxesLineWidthSliderValueChanged, true);
            app.GraphLineWidthSliderLabel = uilabel(app.ControllersPanel);
            app.GraphLineWidthSliderLabel.Text = 'Graph Line Width';
            app.GraphLineWidthSlider = uislider(app.ControllersPanel);
            app.GraphLineWidthSlider.Limits = [0 5];
            app.GraphLineWidthSlider.ValueChangedFcn = createCallbackFcn(app, @GraphLineWidthSliderValueChanged, true);
            app.FigureColorButton = uibutton(app.ControllersPanel, 'push');
            app.FigureColorButton.ButtonPushedFcn = createCallbackFcn(app, @FigureColorButtonPushed, true);
            app.FigureColorButton.Text = 'Figure Color';
            app.GraphColorButton = uibutton(app.ControllersPanel, 'push');
            app.GraphColorButton.ButtonPushedFcn = createCallbackFcn(app, @GraphColorButtonPushed, true);
            app.GraphColorButton.Text = 'Graph Color';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Excel_Grapher_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end